classdef nsMeta < matlab.apps.AppBase
    %NSMETA View and Edit Meta Data for Neurostim Experiments
    %   The gui scans Neurostim data files in a folder and shows the meta
    %   data associated with each experiment. 
    %   
    %   The user can update the meta data, create new meta data, and then
    %   update the information stored in the folder.
    %   
    %   Changes to the subject or paradigm metadata will cause files to be
    %   renamed. Other metadata only update the JSON file associated with
    %   each Neurostim experiment
    %   
    %   Metadata fields are defined in experiment_definition.json
    %   session_definition.json and subject_definition.json files placed n
    %   the root data folder. 
    %   
    %   BK - Dec 2022

    % Properties that correspond to app components
    properties (Access = public)
        NeurostimMetaDataEditorUIFigure  matlab.ui.Figure
        FileMenu                matlab.ui.container.Menu
        ChangeRootFolderMenu    matlab.ui.container.Menu
        ScanFilesMenu           matlab.ui.container.Menu
        Fun                     matlab.ui.control.EditField
        FunEditFieldLabel       matlab.ui.control.Label
        Day                     matlab.ui.control.DropDown
        DayLabel                matlab.ui.control.Label
        Month                   matlab.ui.control.DropDown
        MonthLabel              matlab.ui.control.Label
        Year                    matlab.ui.control.DropDown
        YearDropDownLabel       matlab.ui.control.Label
        PreviousDay             matlab.ui.control.Button
        NextDay                 matlab.ui.control.Button
        AddMetaFieldSubject     matlab.ui.control.Button
        SubjectMetaData         matlab.ui.control.Table
        SubjectsLabel           matlab.ui.control.Label
        AddMetaFieldSession     matlab.ui.control.Button
        SessionMetaData         matlab.ui.control.Table
        SessionsLabel           matlab.ui.control.Label
        ExperimentsLabel        matlab.ui.control.Label
        Paradigm                matlab.ui.control.EditField
        ParadigmEditFieldLabel  matlab.ui.control.Label
        Subject                 matlab.ui.control.EditField
        SubjectEditFieldLabel   matlab.ui.control.Label
        DryRunCheckBox          matlab.ui.control.CheckBox
        AddMetaField            matlab.ui.control.Button
        Update                  matlab.ui.control.Button
        Refresh                 matlab.ui.control.Button
        Root                    matlab.ui.control.EditField
        DataRootEditFieldLabel  matlab.ui.control.Label
        ExperimentMetaData      matlab.ui.control.Table
    end


    properties (Access = private)
        originalExperimentMetaData % The Experiment meta data as read from the files on disk
        originalSessionMetaData % The session meta data as read from disk
        originalSubjectMetaData % The subject meta data as read from disk

        savedStateFields = {'Root','Year','Month','Day','Fun'}; % State will be saved across runs.

        years  % Yeas,months and days found below the root. Saved to disk between runs.
        months
        days
        
        updateNeeded =false;            % Flag that is set when any meta data are changed
        WARNCOLOR = [0.47,0.67,0.19];   % Buttons that should be pushed get this green color
        OKCOLOR  = [0.96,0.96,0.96];    % Default color for buttons that don't need to be pushed.
    end

    methods (Access = private)
        function writeJson(~,str,file)
            %Utility to write readable json files
            str = jsonencode(str,"PrettyPrint",true);
            fid  = fopen(file,'wt');
            fprintf(fid,'%s',str);
            fclose(fid);
        end

        function thisJson= readJson(~,file)
            % Utility to read json files and change all numeric values to
            % strings
            fid =fopen(file,'r');
            txt = fread(fid,inf,'*char');
            fclose(fid);
            thisJson = jsondecode(txt');
            fn = fieldnames(thisJson);
            for j=1:numel(thisJson)
                for i=1:numel(fn)
                    if isnumeric(thisJson(j).(fn{i})) || ischar(thisJson(j).(fn{i}))
                        thisJson(j).(fn{i}) = string(num2str(thisJson(j).(fn{i})));
                    end
                end
            end
        end

        function refreshUI(app,target)
            % Updatae the UI Table based on the new content (called after
            % loading new data)
            if nargin<2
                % Update all
                targets = {'Experiment','Session','Subject'};
                for i=1:numel(targets)
                    refreshUI(app,targets{i});
                end
                return;
            end

            switch (target)
                case 'Experiment'
                    if isempty(app.ExperimentMetaData.Data)                        
                        app.ExperimentMetaData.ColumnName = {'No Neurostim experiment data found. Change root, date, or push refresh.'};
                    else                        
                        app.ExperimentMetaData.ColumnName = app.ExperimentMetaData.Data.Properties.VariableNames;
                        allTrue = true(1,width(app.ExperimentMetaData.Data));
                        app.ExperimentMetaData.ColumnSortable = allTrue;
                        app.ExperimentMetaData.ColumnEditable = allTrue & ~ismember(app.ExperimentMetaData.Data.Properties.VariableNames,{'id','startTime'});                        
                        app.ExperimentMetaData.ColumnWidth = {'fit','fit','fit','fit'};
                    end
                case 'Session'
                    % Updatae the Session UI Table based on the new content (called after
                    % loading new data)
                    if isempty(app.SessionMetaData.Data)
                        app.SessionMetaData.ColumnName = {'No session meta data found.'};
                    else
                        app.SessionMetaData.ColumnName = app.SessionMetaData.Data.Properties.VariableNames;
                        allTrue = true(1,width(app.SessionMetaData.Data));
                        app.SessionMetaData.ColumnSortable = allTrue;
                        app.SessionMetaData.ColumnEditable = allTrue  & ~ismember(app.SessionMetaData.Data.Properties.VariableNames,{'id','subject'});
                        app.SessionMetaData.ColumnWidth = {'fit','fit'};
                    end
                case 'Subject'
                    % Updatae the Subject UI Table based on the new content (called after
                    % loading new data)
                    if isempty(app.SubjectMetaData.Data)
                        app.SubjectMetaData.ColumnName = {'No subject meta data found.'};
                    else
                        app.SubjectMetaData.ColumnName = app.SubjectMetaData.Data.Properties.VariableNames;
                        allTrue = true(1,width(app.SubjectMetaData.Data));
                        app.SubjectMetaData.ColumnSortable = allTrue;
                        app.SubjectMetaData.ColumnEditable = allTrue  & ~ismember(app.SubjectMetaData.Data.Properties.VariableNames,{'id','subject'});
                        app.SubjectMetaData.ColumnWidth = {'fit','fit'};
                    end
            end
            app.Refresh.BackgroundColor = app.OKCOLOR;
        end

        function saveState(app)
            % Save state of the GUI. (Called on exit)
            for i=1:numel(app.savedStateFields)
               setpref('nsMeta',app.savedStateFields{i},app.(app.savedStateFields{i}).Value);               
            end
            alsoSave = {'years','months','days'};
            for i=1:numel(alsoSave)
                setpref('nsMeta',alsoSave{i},app.(alsoSave{i}));               
            end
            
        end

        function loadState(app)
            % Load previous state of the GUI using the Matlab getpref functionality (called on creation)
            alsoSave = {'years','months','days'};
            for i=1:numel(alsoSave)
                app.(alsoSave{i}) = getpref('nsMeta',alsoSave{i}); 
            end
            if ~isempty(app.years)
                app.Year.Items = app.years;
                app.Year.Value = app.years(end);   
                app.YearValueChanged;  % Force an update of the Items of the drop downs
            end
            % Now load stored values
            for i=1:numel(app.savedStateFields)
                if ispref('nsMeta',app.savedStateFields{i})
                    app.(app.savedStateFields{i}).Value = getpref('nsMeta',app.savedStateFields{i});                  
                end
            end
            app.RefreshPushed;          
        end

        function [tExperiment,tSession,tSubject] = nsMetaRead(app)
            % Read all meta data from the folder defined by the Root and
            % the Year/Month/Day settings. 
            root = app.Root.Value;
            if ~exist(root,'dir')
                error('Root folder %s does not exist',root)
            end
            if ispc
                root = strrep(root,'/','\');
            end
            dateFolder = [app.Year.Value '/' app.Month.Value '/' app.Day.Value];
            folder = fullfile(root,dateFolder);
            if ~exist(folder,'dir')
                error('The data folder %s does not exist',folder)
            end
            tExperiment = [];
            tSession = [];
            tSubject = [];
            %%  Find the neurostim data files in the folder
            files= dir(fullfile(folder,'*.mat'));
            if ~isempty(files)
                pathFromRoot = strrep(files(1).folder,root,'');
                nameFromRoot = strcat(pathFromRoot,filesep,{files.name}');
                % Prune to get files that Neurostim generates
                if strcmpi(filesep','\')
                    fs = '\\';
                else
                    fs = filesep;
                end
                pattern = ['(?<date>\d{4,4}' fs '\d{2,2}' fs '\d{2,2})' fs '(?<subject>\w{1,10})\.(?<paradigm>\w+)\.(?<startTime>\d{6,6})\.'];
                metaFromFileName = regexp(nameFromRoot,pattern,'names');
                % Prune those file that did not match
                out = cellfun(@isempty,metaFromFileName);
                metaFromFileName= [metaFromFileName{~out}]; % Create a struct array with the relevant info
                nameFromRoot  = nameFromRoot(~out); %#ok<NASGU>
                files = files(~out);

                % Select on subject/paradigm edit boxes if not empty
                subject = app.Subject.Value;
                if ~isempty(subject)
                    metaFromFileName =metaFromFileName(strcmpi({metaFromFileName.subject},subject));
                end
                paradigm = app.Paradigm.Value;
                if ~isempty(paradigm)
                    metaFromFileName =metaFromFileName(strcmpi({metaFromFileName.paradigm},paradigm));
                end
                nrExperiments = numel(metaFromFileName);
                if nrExperiments==0
                    return;
                end
                % Read associated JSON files (if they exist)
                for i=1:nrExperiments
                    metaFromFileName(i).fullName = fullfile(root,pathFromRoot,files(i).name);
                    [pth,jsonFile] = fileparts(metaFromFileName(i).fullName);
                    jsonFile = fullfile(pth,[jsonFile '.json']);
                    if exist(jsonFile,'file')
                        thisJson = readJson(app,jsonFile);
                    else
                        thisJson = struct;
                    end
                    if i==1
                        metaFromJson = thisJson;
                    else
                        metaFromJson = catstruct(1,metaFromJson,thisJson);
                    end
                end
                metaFieldsFromJson = setxor(fieldnames(metaFromJson),{'provenance'});

                %% Read the current meta definition for experiments
                definitionFile = fullfile(root,'experiment_definition.json');
                app.AddMetaField.Enable = 'on';
                if exist(definitionFile,'file')
                    json = readJson(app,definitionFile);
                    if json.Locked =="1" % The _definition file states that there should be no other meta fields.
                        app.AddMetaField.Enable = 'off';
                    end
                    metaFieldsFromDefinition = fieldnames(json.Fields);
                else
                    metaFieldsFromDefinition = cell(0,1);
                end
                metaFields = unique(cat(1,metaFieldsFromDefinition,metaFieldsFromJson,{'subject','paradigm','startTime'}'));
                nrMetaFields = numel(metaFields);

                % Store folder root and date in the properties so that we
                % can easily reconstruct filenames later
                tExperiment = table('size',[nrExperiments nrMetaFields], 'VariableTypes',repmat({'string'},[1 nrMetaFields]),'VariableNames',metaFields);
                tExperiment =addprop(tExperiment,{'date','root'},{'table','table'}) ;
                tExperiment.Properties.CustomProperties.date  = dateFolder;
                tExperiment.Properties.CustomProperties.root = root;
                % Fill from filename and json files
                for i=1:nrExperiments
                    for j=1:nrMetaFields
                        if isfield(metaFromJson(i),metaFields{j})
                            thisValue = metaFromJson(i).(metaFields{j});
                        elseif isfield(metaFromFileName(i),metaFields{j} )
                            thisValue = metaFromFileName(i).(metaFields{j});
                        else
                            thisValue = "";
                        end
                        tExperiment.(metaFields{j})(i) = thisValue;
                    end
                end
                % Sort to get a consistent order
                tExperiment= sortrows(tExperiment,{'startTime','subject','paradigm'},'ascend');
                % Assign a unique ID to each row, so that we can match up with the original values after the user resorts.
                tExperiment = addvars(tExperiment,strcat('#',string((1:nrExperiments)')),'NewVariableNames','id','before',1);                
                tExperiment = movevars(tExperiment,{'startTime','subject','paradigm'},'After',1);


                %% Setup Session Meta Data
                % Much of this is the same as above for Experiment
                % MetaData, but there are subtle differences 
                % See if there are any Session JSON files
                uSubjects = unique(tExperiment.subject);
                nrSessions = numel(uSubjects);
                metaFromJson =struct([]);
                for i=1:nrSessions
                    sessionJsonFile = fullfile(folder,[uSubjects{i} '.json']);
                    if exist(sessionJsonFile,'file')
                        thisJson = readJson(app,sessionJsonFile);
                    else
                        thisJson = struct;
                    end
                    if isempty(metaFromJson)
                        metaFromJson = thisJson;
                    else
                        metaFromJson = catstruct(1,metaFromJson,thisJson);
                    end
                end
                metaFieldsFromJson = fieldnames(metaFromJson);
                % Retrieve definition
                definitionFile = fullfile(root,'session_definition.json');
                app.AddMetaFieldSession.Enable = 'on';
                if exist(definitionFile,'file')
                    json = readJson(app,definitionFile);
                    if json.Locked =="1" % The _definition file states that there should be no other meta fields.
                        app.AddMetaFieldSession.Enable = 'off';
                    end
                    metaFieldsFromDefinition = fieldnames(json.Fields);
                else
                    metaFieldsFromDefinition = cell(0,1);
                end
                metaFields = unique(cat(1,metaFieldsFromDefinition,metaFieldsFromJson,{'subject'}'));
                nrMetaFields = numel(metaFields);

                % Create table and fill with current folder data
                tSession = table('size',[nrSessions nrMetaFields], 'VariableTypes',repmat({'string'},[1 nrMetaFields]),'VariableNames',metaFields);
                for i=1:nrSessions
                    for j=1:nrMetaFields
                        if isfield(metaFromJson(i),metaFields{j})
                            thisValue = metaFromJson(i).(metaFields{j});
                        elseif strcmpi(metaFields{j},'subject')
                            thisValue = uSubjects{i};
                        else
                            thisValue = "";
                        end
                        tSession.(metaFields{j})(i) = thisValue;
                    end
                end
                % Sort and assign a unique ID to each row.
                tSession = sortrows(tSession,{'subject'},'ascend');                
                tSession = addvars(tSession,strcat('#',string((1:nrSessions)')),'NewVariableNames','id','before',1);
                tSession = movevars(tSession,{'subject'},'after',1);


                %% Get Subject meta data
                % Again much overlap with the code above, but also some
                % differences.
                % Both the definition and the actual subject meta data are
                % in the Root folder.
                % Retrieve definition
                definitionFile = fullfile(root,'subject_definition.json');
                app.AddMetaFieldSubject.Enable = 'on';
                if exist(definitionFile,'file')
                    json = readJson(app,definitionFile);
                    if json.Locked =="1" % No new meta fields allowed according to _definition
                        app.AddMetaFieldSubject.Enable = 'off';
                    end
                    metaFieldsFromDefinition = fieldnames(json.Fields);
                else
                    metaFieldsFromDefinition = cell(0,1);
                end

                metaDataFile =  fullfile(root,'subject.json');
                if exist(metaDataFile,'file')
                    metaFromJson = readJson(app,metaDataFile);
                    metaFieldsFromJson = fieldnames(metaFromJson);
                else
                    metaFromJson = struct;
                    metaFieldsFromJson = cell(0,1);
                end
                metaFields = unique(cat(1,metaFieldsFromDefinition,metaFieldsFromJson,{'subject'}'));
                nrMetaFields = numel(metaFields);

                % Create table and fill with current folder data
                tSubject = table('size',[nrSessions nrMetaFields], 'VariableTypes',repmat({'string'},[1 nrMetaFields]),'VariableNames',metaFields);
                for i=1:nrSessions
                    if isfield(metaFromJson,'subject')
                        thisMetaFromJson = metaFromJson(uSubjects(i)==cat(1,metaFromJson.subject));
                    else
                        thisMetaFromJson = struct;
                    end
                    for j=1:nrMetaFields
                        if ~isempty(thisMetaFromJson) && isfield(thisMetaFromJson,metaFields{j})
                            thisValue =thisMetaFromJson.(metaFields{j});
                        elseif strcmpi(metaFields{j},'subject')
                            thisValue = uSubjects{i};
                        else
                            thisValue = "";
                        end
                        tSubject.(metaFields{j})(i) = thisValue;
                    end
                end
                % Sort and assign a unique ID to each row.
                tSubject= sortrows(tSubject,{'subject'},'ascend');            
                tSubject = addvars(tSubject,strcat('#',string((1:nrSessions)')),'NewVariableNames','id','before',1);
                tSubject = movevars(tSubject,{'subject'},'after',1);
            end
        end

        function [ok,provenance]= rename(app,folder,oldSubject,oldParadigm,oldStartTime,newSubject,newParadigm)
            % Rename a Neurostim file and its associated files (same
            % prefix); this is needed when either the subject or the
            % paradigm changes.  The stored CIC object is updated
            % accordinly, and the change is logged in the provenance field of the
            % JSON file.
            if app.DryRunCheckBox.Value
                dryRunText = '[DRYRUN]';
                dryRun  = true;
            else
                dryRun  = false;
                dryRunText  = '';
            end
            provenance = '';
            ok = true;
            oldPrefix = sprintf('%s.%s.%s',oldSubject,oldParadigm,oldStartTime);
            oldFullName = fullfile(folder,[oldPrefix '.mat']);
            if ~exist(oldFullName,'file')
                ok = false;
                fprintf('File does not exist %s\n',oldFullName)
                return
            else
                load(oldFullName,'c');
            end
            % Update CIC and save to a new file.
            c.subject = newSubject;
            c.paradigm = newParadigm;
            % Create the new file name
            newPrefix = sprintf('%s.%s.%s',newSubject,newParadigm,oldStartTime);
            newFullName = fullfile(folder,[newPrefix '.mat']);
            if dryRun
                ok = true;
            else
                save(newFullName,'c');
                if exist(newFullName,'file')
                    delete(oldFullName);
                else
                    ok = false;
                end
            end
            if ok
                fprintf('%s Updated %s and renamed to %s\n',dryRunText, oldFullName, newFullName);
            else
                fprintf('Unable to create %s\n',newFullName)
                return;
            end
            %% Move associated data files (same prefix)
            files= dir(fullfile(folder,[oldPrefix '*']));
            for i=1:numel(files)
                thisOldName = fullfile(folder,files(i).name);
                thisNewName = strrep(thisOldName,oldPrefix,newPrefix);
                if dryRun
                    status=1;
                else
                    [status, message] = movefile(thisOldName,thisNewName);
                end
                if status ==0
                    fprintf(2,'Failed to move %s to %s (%s)\n',thisOldName,thisNewName,message);
                    ok = false;
                else
                    fprintf('%s \t Renamed associated file %s to %s\n',dryRunText, files(i).name, strrep(thisNewName,folder,''));
                end
            end
            provenance = jsondecode(sprintf('{"Date":"%s", "File":"%s"}',datestr(now,'yyyymmddTHHMMSS'),oldPrefix));
        end

        function out = addNewMeta(app,target,fields)
            % Add a new meta data field to the meta data.
            if ischar(target) || isstring(target)
                org = app.(['original' target 'MetaData']);
                editable = app.([target 'MetaData']).Data;
                inApp = true;
            else
                org =[];
                editable =target;
                inApp = false;
            end
            nrRows = height(editable);
            for i=1:numel(fields)
                if ismember(fields{i},editable.Properties.VariableNames)
                    % Already in table. Skip
                    continue;
                end
                if inApp
                    org = addvars(org,repmat("",[nrRows,1]),'NewVariableNames',fields{i});
                end
                editable    = addvars(editable,repmat("",[nrRows,1]),'NewVariableNames',fields{i});
            end
            if inApp
                refreshUI(app,target);
                out = [];
            else
                % Return the updated table
                out = editable;
            end

        end

%  No longer needed now that .updateNeeded is updated by any change in the uitables.      
% function tf = updateNeeded(app)
%             % Scans the metadata for the current folder and returns true if
%             % any of them have changed since the data were loaded.
%             tf = false;
%             if  ~isempty(app.ExperimentMetaData.Data)
%                 newExperimentMetaData = sortrows(app.ExperimentMetaData.Data,'id');
%                 oldExperimentMetaData = sortrows(app.originalExperimentMetaData,'id');
%                 jsonFieldsExperiment = setxor(newExperimentMetaData.Properties.VariableNames,{'id','subject','paradigm','startTime'})';
%                 for i=1:height(oldExperimentMetaData)
%                     % Check subject/paradigm changes
%                     tf =  ~strcmpi(newExperimentMetaData{i,'subject'},oldExperimentMetaData{i,'subject'})  || ~strcmpi(newExperimentMetaData{i,'paradigm'},oldExperimentMetaData{i,'paradigm'});
%                     % Check other meta change
%                     tf = tf || any(~cellfun(@(x) isequal(oldExperimentMetaData{i,x},newExperimentMetaData{i,x}),jsonFieldsExperiment));
%                     if tf;break;end
%                 end
%                 if tf;return;end
%             end
%             if ~isempty(app.SessionMetaData.Data)
%                 oldSessionMetaData = app.originalSessionMetaData;
%                 newSessionMetaData = app.SessionMetaData.Data;
%                 jsonFieldsSession = setxor(newSessionMetaData.Properties.VariableNames,{'id','subject'})';
%                 for i=1:height(oldSessionMetaData)
%                     %% Check whetehr the meta data that came from a JSON file changed
%                     tf = any(~cellfun(@(x) isequal(oldSessionMetaData{i,x},newSessionMetaData{i,x}),jsonFieldsSession));
%                     if tf;break;end
%                 end
%                 if tf;return;end
%             end
% 
%             if ~isempty(app.SubjectMetaData.Data)
%                 oldSubjectMetaData = app.originalSubjectMetaData;
%                 newSubjectMetaData = app.SubjectMetaData.Data;
%                 jsonFieldsSubject = setxor(newSubjectMetaData.Properties.VariableNames,{'id'})';
%                 for i=1:height(oldSubjectMetaData)
%                     %% Check whetehr the meta data that came from a JSON file changed
%                     tf = any(~cellfun(@(x) isequal(oldSubjectMetaData{i,x},newSubjectMetaData{i,x}),jsonFieldsSubject));
%                     if tf;break;end
%                 end
%             end
%         end
        
        function guiState(app,value)
            
            app.Year.Enable=value;
            app.Month.Enable=value;
            app.Day.Enable=value;
            app.NextDay.Enable =value;
            app.PreviousDay.Enable = value;
            if strcmpi(value,'off')
            app.ExperimentMetaData.Data= table;
            app.SessionMetaData.Data= table;
            app.SubjectMetaData.Data= table;
            app.originalExperimentMetaData = table;
            app.originalSessionMetaData = table;
            app.originalSubjectMetaData = table;
             refreshUI(app)
            end
           
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            loadState(app);
            refreshUI(app,'Experiment');
        end

        % Button pushed function: Refresh
        function RefreshPushed(app, event)
            app.NeurostimMetaDataEditorUIFigure.Pointer = 'watch';
            drawnow;
            %% Refresh from folder
            [app.originalExperimentMetaData,app.originalSessionMetaData,app.originalSubjectMetaData] = nsMetaRead(app);
            if isempty(app.originalExperimentMetaData)
                exptDataWithoutProvenance = app.originalExperimentMetaData;
            else
                isProvenance = ismember(app.originalExperimentMetaData.Properties.VariableNames,'provenance');
                exptDataWithoutProvenance  = app.originalExperimentMetaData(:,~isProvenance); % Editable copy in the UI.
            end
            app.ExperimentMetaData.Data =     exptDataWithoutProvenance;
            app.SessionMetaData.Data = app.originalSessionMetaData;
            app.SubjectMetaData.Data = app.originalSubjectMetaData;
            
            refreshUI(app)
            app.updateNeeded  =false;
            app.Update.BackgroundColor = app.OKCOLOR;
            app.NeurostimMetaDataEditorUIFigure.Pointer = 'arrow';
            drawnow;
        end

        % Button pushed function: Update
        function UpdatePushed(app, event)
            app.NeurostimMetaDataEditorUIFigure.Pointer = 'watch';
            drawnow;
          
            if app.DryRunCheckBox.Value
                dryRunText = '[DRYRUN]';
                dryRun  = true;
            else
                dryRun  = false;
                dryRunText  = '';
            end
            fprintf('****** Updating meta data %s *******\n',datestr(now,"HH:MM:SS"))

            newExperimentMetaData = sortrows(app.ExperimentMetaData.Data,'id');
            oldExperimentMetaData = sortrows(app.originalExperimentMetaData,'id');
            folder = fullfile(newExperimentMetaData.Properties.CustomProperties.root,newExperimentMetaData.Properties.CustomProperties.date);
            jsonFieldsExperiment = setxor(newExperimentMetaData.Properties.VariableNames,{'id','subject','paradigm','startTime'})';
            for i=1:height(oldExperimentMetaData)
                %% Check (meta) data that are stored in the file and the filename (only subject and paradigm are allowed to be changed).
                % and update if needed.
                if ~strcmpi(newExperimentMetaData{i,'subject'},oldExperimentMetaData{i,'subject'})  || ~strcmpi(newExperimentMetaData{i,'paradigm'},oldExperimentMetaData{i,'paradigm'})
                    % Have to read the file to update the CIC object
                    % inside.
                    [~,provenance] = rename(app,folder,oldExperimentMetaData.subject{i},oldExperimentMetaData.paradigm{i},oldExperimentMetaData.startTime{i},newExperimentMetaData.subject{i},newExperimentMetaData.paradigm{i});
                    jsonNeedsUpdate = true;
                else
                    %% Check whetehr the meta data that came from a JSON file changed
                    jsonNeedsUpdate = any(~cellfun(@(x) isequal(oldExperimentMetaData{i,x},newExperimentMetaData{i,x}),jsonFieldsExperiment));
                    provenance = struct;
                end

                if jsonNeedsUpdate
                    jsonFullFile =  fullfile(folder,sprintf('%s.%s.%s.json',newExperimentMetaData.subject{i},newExperimentMetaData.paradigm{i},newExperimentMetaData.startTime{i}));
                    jsonStruct = table2struct(newExperimentMetaData(i,jsonFieldsExperiment));
                    if ismember('provenance',oldExperimentMetaData.Properties.VariableNames)
                        provenance = cat(1,provenance,oldExperimentMetaData{i,'provenance'}{1});
                    end
                    if ~isempty(provenance)
                        jsonStruct.provenance = provenance;
                    end
                    if dryRun
                        %Nothing to do
                    else
                        app.writeJson(jsonStruct,jsonFullFile);
                    end
                    fprintf('%s Updated JSON file %s\n',dryRunText, jsonFullFile);
                end
            end

            %% Update session data
            oldSessionMetaData = app.originalSessionMetaData;
            newSessionMetaData = app.SessionMetaData.Data;
            jsonFieldsSession = setxor(newSessionMetaData.Properties.VariableNames,{'id','subject'})';
            for i=1:height(oldSessionMetaData)
                %% Check whetehr the meta data that came from a JSON file changed
                jsonNeedsUpdate = any(~cellfun(@(x) isequal(oldSessionMetaData{i,x},newSessionMetaData{i,x}),jsonFieldsSession));
                if jsonNeedsUpdate
                    jsonFullFile = fullfile(folder,sprintf('%s.json',newSessionMetaData.subject{i}));
                    jsonStruct = table2struct(newSessionMetaData(i,jsonFieldsSession));
                    if dryRun
                        %Nothing to do
                    else
                        app.writeJson(jsonStruct,jsonFullFile);
                    end
                    fprintf('%s Updated JSON file %s\n',dryRunText, jsonFullFile);
                end
            end
            %% Update subject data
            oldSubjectMetaData = app.originalSubjectMetaData;
            newSubjectMetaData = app.SubjectMetaData.Data;
            jsonFieldsSubject = setxor(newSubjectMetaData.Properties.VariableNames,{'id'})';
            jsonFullFile = fullfile(app.Root.Value,'subject.json');
            if exist(jsonFullFile,'file')
                jsonSubjects = readJson(app,jsonFullFile);
            else
                jsonSubjects = struct([]);
            end
            for i=1:height(oldSubjectMetaData)
                %% Check whetehr the meta data that came from a JSON file changed
                jsonNeedsUpdate = any(~cellfun(@(x) isequal(oldSubjectMetaData{i,x},newSubjectMetaData{i,x}),jsonFieldsSubject));
                if jsonNeedsUpdate
                    thisJson = table2struct(newSubjectMetaData(i,jsonFieldsSubject));
                    if isempty(jsonSubjects)
                        jsonSubjects = thisJson;
                    else
                        ix = [jsonSubjects.subject] == thisJson.subject;
                        if any(ix)
                            jsonSubjects(ix) = [];
                        end
                        if numel(jsonSubjects)==0
                            jsonSubjects =thisJson;
                        else
                            jsonSubjects = catstruct(1,jsonSubjects,thisJson);
                        end
                    end
                end
            end
            if dryRun
                %Nothing to do
            else
                app.writeJson(jsonSubjects,jsonFullFile);
            end

            fprintf('%s Updated JSON file %s\n',dryRunText, jsonFullFile);
            if dryRun
                fprintf('%s No changes were made\n',dryRunText)
            else        
                % Force an update to get old and new metadata in sync again.
                app.RefreshPushed;
            end
            fprintf('****** Updating meta data complete *******\n')
            app.NeurostimMetaDataEditorUIFigure.Pointer = 'arrow';
            drawnow;
            
        end

        % Button pushed function: AddMetaField
        function AddMetaFieldPushed(app, event)
            % Allow user to add a meta data field. Initial values are
            % empty, but can be filled in the GUI and will then be saved
            % (only for the rows that changed from empty).
            newField = inputdlg('Fieldname?','Provide the name for a new meta data field.');
            if ~isempty(newField)
                addNewMeta(app,'Experiment',newField)
            end
        end

        % Close request function: NeurostimMetaDataEditorUIFigure
        function NeurostimMetaDataEditorUIFigureCloseRequest(app, event)
            % Shutting down. 
           if  app.updateNeeded
                answer = questdlg('Changes have not been saved.','Unsaved Changes', 'Discard','Update','Update');
                if strcmpi(answer,'Update')
                    app.UpdatePushed;
                end
            end         
            saveState(app)
            delete(app)
        end

        % Menu selected function: ChangeRootFolderMenu
        function ChangeRootFolderMenuSelected(app, event)
            % Convenience function to select a root folder with a  gui.
            folder = uigetdir(app.Root.Value);
            if folder~=0 % folder ==0 is cancel
                app.Root.Value= folder;
            end
           app.RootValueChanged;
        end

        % Button pushed function: AddMetaFieldSession
        function AddMetaFieldSessionButtonPushed(app, event)
            % Allow user to add a meta data field to the Sessions table. Initial values are
            % empty, but can be filled in the GUI and will then be saved
            % (only for the rows that changed from empty).
            newField = inputdlg('Fieldname?','Provide the name for a new meta data field.');
            if ~isempty(newField)
                addNewMeta(app,'Session',newField)
            end
        end

        % Button pushed function: AddMetaFieldSubject
        function AddMetaFieldSubjectButtonPushed(app, event)
            newField = inputdlg('Fieldname?','Provide the name for a new meta data field.');
            if ~isempty(newField)
                addNewMeta(app,'Subject',newField)
            end
        end

        % Button pushed function: NextDay
        function NextDayButtonPushed(app, event)
            %% First check whether an update is needed.
            if  app.updateNeeded
                answer = questdlg('Changes have not been saved.','Unsaved Changes', 'Discard','Update','Update');
                if strcmpi(answer,'Update')
                    app.UpdatePushed;
                end
            end            

            % Then try to move to the next day if it exists.
            current = find(ismember(app.Day.Items,app.Day.Value));
            if current < numel(app.Day.Items)
                app.Day.Value = app.Day.Items{current+1};
            else
                % Last day of the month
                currentMonth =  find(ismember(app.Month.Items,app.Month.Value));
                if currentMonth < numel(app.Month.Items)
                    app.Month.Value = app.Month.Items{currentMonth+1};
                    app.MonthValueChanged;
                    app.Day.Value = app.Day.Items{1};
                else
                    % Last month of the year
                    currentYear =  find(ismember(app.Year.Items,app.Year.Value));
                    if currentYear < numel(app.Year.Items)
                        app.Year.Value = app.Year.Items{currentYear+1};
                        app.YearValueChanged;
                        app.Month.Value = app.Month.Items{1};
                        app.MonthValueChanged;
                        app.Day.Value = app.Day.Items{1};
                    else
                        fprintf('This is the end...\n')
                    end
                end
            end
            app.RefreshPushed
        end

        % Button pushed function: PreviousDay
        function PreviousDayButtonPushed(app, event)
            % Check to see if updates are needed.
            if  app.updateNeeded
                answer = questdlg('Changes have not been saved.','Unsaved Changes', 'Discard','Update','Update');
                if strcmpi(answer,'Update')
                    app.UpdatePushed;
                end
            end        
            % Then move to the previous day in the list.
            current = find(ismember(app.Day.Items,app.Day.Value));
            if current >1
                app.Day.Value = app.Day.Items{current-1};
            else
                % First day of the month
                currentMonth =  find(ismember(app.Month.Items,app.Month.Value));
                if currentMonth > 1
                    app.Month.Value = app.Month.Items{currentMonth-1};
                    app.MonthValueChanged;
                    app.Day.Value = app.Day.Items{end};
                else
                    % First month of the year
                    currentYear =  find(ismember(app.Year.Items,app.Year.Value));
                    if currentYear > 1
                        app.Year.Value = app.Year.Items{currentYear-1};
                        app.YearValueChanged;
                        app.Month.Value = app.Month.Items{end};
                        app.MonthValueChanged;
                        app.Day.Value = app.Day.Items{end};
                    else
                        fprintf('This is the beginning ...\n')
                    end
                end
            end
            app.RefreshPushed


        end

        % Value changed function: Root
        function RootValueChanged(app, event)
            % This scans the Root folder recursively to extract
            % Year/Month/Days. 
            app.NeurostimMetaDataEditorUIFigure.Pointer = 'watch';
            drawnow;
          
            root = app.Root.Value;
            app.years ={};
            app.months = {};
            app.days = {};
            yrs = dir([root '/*']);
            match = regexp({yrs.name},'(?<year>\d{4,4})','match');
            if isempty(match)
                app.guiState('off');
            else
                app.years = [match{:}];
                keepYear = true(size(app.years));
                for yr= 1:numel(app.years)
                    mnths = dir(fullfile(root,[app.years{yr} '/*']));
                    match =  regexp({mnths.name},'(?<month>\d{2,2})','match');
                    mnths = [match{:}];
                    if isempty(mnths)
                       keepYear(yr)= false;
                       continue;
                    else
                       app.months{yr} = mnths;
                    end
                    keepMonth= true(size(app.months{yr}));
                    for mo = 1:numel(app.months{yr})
                        dys= dir(fullfile(root,app.years{yr},[app.months{yr}{mo} '/*']));
                        match =  regexp({dys.name},'(?<month>\d{2,2})','match');
                        dys = [match{:}];
                        if isempty(dys)
                            keepMonth(mo)=false;                            
                        else
                            keepDays = true(size(dys));
                            if  app.ScanFilesMenu.Checked
                                % Time consuming, so optional.
                                for dy=1:numel(dys)
                                    keepDays(dy) = ~isempty(dir(fullfile(root,app.years{yr},app.months{yr}{mo},[dys{dy} '/*.mat'])));
                                end
                            end
                            dys =dys(keepDays);
                            if isempty(dys)
                                keepMonth(mo) =false;
                            else
                                app.days{yr}{mo} = dys;
                            end
                        end
                    end    
                    if any(keepMonth)
                        app.days{yr} = app.days{yr}(keepMonth);
                    else
                        app.days{yr} = []; % Will be removed below
                    end
                    if ~any(keepMonth)
                        app.months{yr} = [];  % Will be removed below
                        keepYear(yr) =false;
                    else
                        app.months{yr} = app.months{yr}(keepMonth);
                    end

                end
                app.years = app.years(keepYear);
                app.months = app.months(keepYear);
                app.days = app.days(keepYear);
                app.guiState('on');                
                app.Year.Items = app.years;
                app.Year.Value = app.years(end);
                YearValueChanged(app, [])
        
            end
            app.NeurostimMetaDataEditorUIFigure.Pointer = 'arrow';  
            drawnow;

        end

        % Value changed function: Year
        function YearValueChanged(app, event)
            value = app.Year.Value;           
            app.Month.Items = app.months{ismember(app.years,value)};
            app.Month.Value  = app.Month.Items{1};
            app.MonthValueChanged;            
        end

        % Value changed function: Month
        function MonthValueChanged(app, event)

            yrIx = ismember(app.years,app.Year.Value);
            moIx = ismember(app.months{yrIx},app.Month.Value);
            app.Day.Items = app.days{yrIx}{moIx};
            app.Day.Value = app.Day.Items{1};
            app.DayValueChanged;
        end

        % Value changed function: Day
        function DayValueChanged(app, event)
            app.Refresh.BackgroundColor = app.WARNCOLOR; % Signal need to refresh

        end

        % Cell edit callback: ExperimentMetaData, SessionMetaData, 
        % SubjectMetaData
        function MetaDataCellEdit(app, event)
            if event.NewData~=event.PreviousData
                app.updateNeeded = true;
                app.Update.BackgroundColor = app.WARNCOLOR;
            end
        end

        % Menu selected function: ScanFilesMenu
        function ScanFilesMenuSelected(app, event)
            % Toggle and rescan
            if app.ScanFilesMenu.Checked
                app.ScanFilesMenu.Checked =false;
            else                
                app.ScanFilesMenu.Checked =true;                
            end
            app.RootValueChanged;
                
        end

        % Value changed function: Paradigm
        function ParadigmValueChanged(app, event)
            app.RefreshPushed;
        end

        % Value changed function: Subject
        function SubjectValueChanged(app, event)
            app.RefreshPushed;
        end

        % Button down function: ExperimentMetaData
        function ExperimentMetaDataButtonDown(app, event)
            
        end

        % Key release function: NeurostimMetaDataEditorUIFigure
        function NeurostimMetaDataEditorUIFigureKeyRelease(app, event)
            key = event.Key;
            
        end

        % Key release function: ExperimentMetaData
        function ExperimentMetaDataKeyRelease(app, event)
            key = event.Key;
            switch upper(key)
                case {'R','F'}
                    % Read the file and put in 'c' in the base workspace.                    
                    id = app.ExperimentMetaData.Data{app.ExperimentMetaData.Selection(1),'id'};
                    % Find the row in the original data in case there is a
                    % unsaved change in the metadata that determine the
                    % filename
                    row = find(app.originalExperimentMetaData.id==id);
                    fname = fullfile(app.Root.Value,sprintf('%s/%s/%s',app.Year.Value,app.Month.Value,app.Day.Value),...
                                sprintf('%s.%s.%s.mat',app.originalExperimentMetaData{row,'subject'},app.originalExperimentMetaData{row,'paradigm'},app.originalExperimentMetaData{row,'startTime'}));
                    fname= strrep(fname,'\','/');
                    if exist(fname,'file')
                       load(fname,'c');
                        assignin('base',"c",c);
                        fprintf('%s is now assigned to variable ''c''\n',fname)
                        if strcmpi(key,'F') && ~isempty(app.Fun.Value)
                                evalin('base',app.Fun.Value)
                        end
                    else
                        fprintf(2,'%s does not exist?\n',fname)
                    end
            end

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.NeurostimMetaDataEditorUIFigure = uifigure('Visible', 'off');
            app.NeurostimMetaDataEditorUIFigure.Name = 'Neurostim Meta Data Editor';
            app.NeurostimMetaDataEditorUIFigure.CloseRequestFcn = createCallbackFcn(app, @NeurostimMetaDataEditorUIFigureCloseRequest, true);
            app.NeurostimMetaDataEditorUIFigure.KeyReleaseFcn = createCallbackFcn(app, @NeurostimMetaDataEditorUIFigureKeyRelease, true);
            app.FileMenu = uimenu(app.NeurostimMetaDataEditorUIFigure);
            app.FileMenu.Text = 'File';
            app.ChangeRootFolderMenu = uimenu(app.FileMenu);
            app.ChangeRootFolderMenu.MenuSelectedFcn = createCallbackFcn(app, @ChangeRootFolderMenuSelected, true);
            app.ChangeRootFolderMenu.Tooltip = {'Use a dialog box to select the root folder.'};
            app.ChangeRootFolderMenu.Accelerator = 'R';
            app.ChangeRootFolderMenu.Text = 'Change Root Folder';
            app.ScanFilesMenu = uimenu(app.FileMenu);
            app.ScanFilesMenu.MenuSelectedFcn = createCallbackFcn(app, @ScanFilesMenuSelected, true);
            app.ScanFilesMenu.Tooltip = {'Set this to true to scan down to the file level when determining which folders have data. This is a slower initial search , but will list only folders with data in the Year/Mont/Day drop down menus. '};
            app.ScanFilesMenu.Accelerator = 'S';
            app.ScanFilesMenu.Text = 'Scan Files';
            app.ExperimentMetaData = uitable(app.NeurostimMetaDataEditorUIFigure);
            app.ExperimentMetaData.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.ExperimentMetaData.RowName = {};
            app.ExperimentMetaData.CellEditCallback = createCallbackFcn(app, @MetaDataCellEdit, true);
            app.ExperimentMetaData.Tooltip = {'Meta data for each Neurostim experiment on this day. '; ''; 'Sort by clicking on any of the column headers. All columsn except ''startTime'' are editable. '; ''; 'Changes to the meta data are only written to disk after pressing the update button. '};
            app.ExperimentMetaData.ButtonDownFcn = createCallbackFcn(app, @ExperimentMetaDataButtonDown, true);
            app.ExperimentMetaData.KeyReleaseFcn = createCallbackFcn(app, @ExperimentMetaDataKeyRelease, true);
            app.DataRootEditFieldLabel = uilabel(app.NeurostimMetaDataEditorUIFigure);
            app.DataRootEditFieldLabel.Text = 'Data Root';
            app.Root = uieditfield(app.NeurostimMetaDataEditorUIFigure, 'text');
            app.Root.ValueChangedFcn = createCallbackFcn(app, @RootValueChanged, true);
            app.Root.Tooltip = {'The root folder for all data. (It contains the folders named after the years). '};
            app.Root.Value = 'z:/klab/';
            app.Refresh = uibutton(app.NeurostimMetaDataEditorUIFigure, 'push');
            app.Refresh.ButtonPushedFcn = createCallbackFcn(app, @RefreshPushed, true);
            app.Refresh.Tooltip = {'Click to refresh the list. When this is green, a refresh is needed. '};
            app.Refresh.Text = 'Refresh';
            app.Update = uibutton(app.NeurostimMetaDataEditorUIFigure, 'push');
            app.Update.ButtonPushedFcn = createCallbackFcn(app, @UpdatePushed, true);
            app.Update.Tooltip = {'Click to write the changed meta data to disk. '; 'This may move and change data files. '; ''; 'When this button is green, an update is needed (or changes will be discarded on the next refresh).'};
            app.Update.Text = 'Update';
            app.AddMetaField = uibutton(app.NeurostimMetaDataEditorUIFigure, 'push');
            app.AddMetaField.ButtonPushedFcn = createCallbackFcn(app, @AddMetaFieldPushed, true);
            app.AddMetaField.Tooltip = {'Click to add a new meta data fiield. This can be disabled with the Locked setting in the _definition file.'};
            app.AddMetaField.Text = 'Add Meta Field';
            app.DryRunCheckBox = uicheckbox(app.NeurostimMetaDataEditorUIFigure);
            app.DryRunCheckBox.Tooltip = {'Select this and then press update to get a list of changes that would be made.'};
            app.DryRunCheckBox.Text = 'Dry Run';
            app.SubjectEditFieldLabel = uilabel(app.NeurostimMetaDataEditorUIFigure);
            app.SubjectEditFieldLabel.Text = 'Subject';
            app.Subject = uieditfield(app.NeurostimMetaDataEditorUIFigure, 'text');
            app.Subject.ValueChangedFcn = createCallbackFcn(app, @SubjectValueChanged, true);
            app.Subject.Tooltip = {'Restrict to a single subject (Optional).'};
            app.ParadigmEditFieldLabel = uilabel(app.NeurostimMetaDataEditorUIFigure);
            app.ParadigmEditFieldLabel.Text = 'Paradigm';
            app.Paradigm = uieditfield(app.NeurostimMetaDataEditorUIFigure, 'text');
            app.Paradigm.ValueChangedFcn = createCallbackFcn(app, @ParadigmValueChanged, true);
            app.Paradigm.Tooltip = {'Restrict to a specific paradigm (Optional).'};
            app.ExperimentsLabel = uilabel(app.NeurostimMetaDataEditorUIFigure);
            app.ExperimentsLabel.Text = 'Experiments';
            app.SessionsLabel = uilabel(app.NeurostimMetaDataEditorUIFigure);
            app.SessionsLabel.Text = 'Sessions';
            app.SessionMetaData = uitable(app.NeurostimMetaDataEditorUIFigure);
            app.SessionMetaData.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.SessionMetaData.RowName = {};
            app.SessionMetaData.CellEditCallback = createCallbackFcn(app, @MetaDataCellEdit, true);
            app.SessionMetaData.Tooltip = {'Meta data for each Session (i.e. all experiments performed by a subject on a given day) '; ''; 'Sort it by clicking on the column headers. All columns are editable. '; ''; 'Changes to the meta data are only written to disk after pressing the update button. '};
            app.AddMetaFieldSession = uibutton(app.NeurostimMetaDataEditorUIFigure, 'push');
            app.AddMetaFieldSession.ButtonPushedFcn = createCallbackFcn(app, @AddMetaFieldSessionButtonPushed, true);
            app.AddMetaFieldSession.Tooltip = {'Click to add a new meta data fiield. This can be disabled with the Locked setting in the _definition file.'};
            app.AddMetaFieldSession.Text = 'Add Meta Field';
            app.SubjectsLabel = uilabel(app.NeurostimMetaDataEditorUIFigure);
            app.SubjectsLabel.Text = 'Subjects';
            app.SubjectMetaData = uitable(app.NeurostimMetaDataEditorUIFigure);
            app.SubjectMetaData.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.SubjectMetaData.RowName = {};
            app.SubjectMetaData.CellEditCallback = createCallbackFcn(app, @MetaDataCellEdit, true);
            app.SubjectMetaData.Tooltip = {'Meta data for each Subject. '; ''; 'Sort by clicking on any of the column headers. '; 'All columsn are editable. '; ''; 'Changes to the meta data are only written to disk after pressing the update button. '};
            app.AddMetaFieldSubject = uibutton(app.NeurostimMetaDataEditorUIFigure, 'push');
            app.AddMetaFieldSubject.ButtonPushedFcn = createCallbackFcn(app, @AddMetaFieldSubjectButtonPushed, true);
            app.AddMetaFieldSubject.Tooltip = {'Click to add a new meta data fiield. This can be disabled with the Locked setting in the _definition file.'};
            app.AddMetaFieldSubject.Text = 'Add Meta Field';
            app.NextDay = uibutton(app.NeurostimMetaDataEditorUIFigure, 'push');
            app.NextDay.ButtonPushedFcn = createCallbackFcn(app, @NextDayButtonPushed, true);
            app.NextDay.Tooltip = {'Go to the next day (data will update automatically).'};
            app.NextDay.Text = '>';
            app.PreviousDay = uibutton(app.NeurostimMetaDataEditorUIFigure, 'push');
            app.PreviousDay.ButtonPushedFcn = createCallbackFcn(app, @PreviousDayButtonPushed, true);
            app.PreviousDay.Tooltip = {'Go to the previous day (data will update automatically).'};
            app.PreviousDay.Text = '<';
            app.YearDropDownLabel = uilabel(app.NeurostimMetaDataEditorUIFigure);
            app.YearDropDownLabel.Text = 'Year';
            app.Year = uidropdown(app.NeurostimMetaDataEditorUIFigure);
            app.Year.ValueChangedFcn = createCallbackFcn(app, @YearValueChanged, true);
            app.Year.Tooltip = {'Pick a year (data will not update automatically; use the refresh button).'};
            app.MonthLabel = uilabel(app.NeurostimMetaDataEditorUIFigure);
            app.MonthLabel.Text = 'Month';
            app.Month = uidropdown(app.NeurostimMetaDataEditorUIFigure);
            app.Month.ValueChangedFcn = createCallbackFcn(app, @MonthValueChanged, true);
            app.Month.Tooltip = {'Pick a month (data will not update automatically; use the refresh button).'};
            app.DayLabel = uilabel(app.NeurostimMetaDataEditorUIFigure);
            app.DayLabel.Text = 'Day';
            app.Day = uidropdown(app.NeurostimMetaDataEditorUIFigure);
            app.Day.ValueChangedFcn = createCallbackFcn(app, @DayValueChanged, true);
            app.Day.Tooltip = {'Pick a day (data will not update automatically; use the refresh button).'};
            app.FunEditFieldLabel = uilabel(app.NeurostimMetaDataEditorUIFigure);
            app.FunEditFieldLabel.Text = 'Fun';
            app.Fun = uieditfield(app.NeurostimMetaDataEditorUIFigure, 'text');
            app.Fun.Tooltip = {'After pressing ''F'', this code will execute on the command line after loading the data from the selected experiment as ''c''. For isntance, c.trial  will show the last trial number in this Experiment. To only load the file without executing code, press ''R''; that will make it available on the command line as ''c''.'};
            % Show the figure after all components are created
            app.NeurostimMetaDataEditorUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = nsMeta

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.NeurostimMetaDataEditorUIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.NeurostimMetaDataEditorUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.NeurostimMetaDataEditorUIFigure)
        end
    end
end